classdef NodeInterface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        InitializeButton             matlab.ui.control.Button
        DataTable                    matlab.ui.control.Table
        LinkTable                    matlab.ui.control.Table
        RoutingGraph                 matlab.ui.control.UIAxes
        MessageTrafficTextAreaLabel  matlab.ui.control.Label
        MessageTraffic               matlab.ui.control.TextArea
        GetDataButton                matlab.ui.control.Button
        Refresh_1                    matlab.ui.control.Button
        Query_1                      matlab.ui.control.Button
        Node1Label                   matlab.ui.control.Label
        Refresh_2                    matlab.ui.control.Button
        Query_2                      matlab.ui.control.Button
        Node2Label                   matlab.ui.control.Label
        Refresh_3                    matlab.ui.control.Button
        Query_3                      matlab.ui.control.Button
        Node3Label                   matlab.ui.control.Label
        Refresh_4                    matlab.ui.control.Button
        Query_4                      matlab.ui.control.Button
        Node4Label                   matlab.ui.control.Label
        Refresh_5                    matlab.ui.control.Button
        Query_5                      matlab.ui.control.Button
        Node5Label                   matlab.ui.control.Label
        Refresh_6                    matlab.ui.control.Button
        Query_6                      matlab.ui.control.Button
        Node6Label                   matlab.ui.control.Label
        Refresh_7                    matlab.ui.control.Button
        Query_7                      matlab.ui.control.Button
        Node7Label                   matlab.ui.control.Label
        SensorInterfaceLabel         matlab.ui.control.Label
        BaseStationRefreshButton     matlab.ui.control.Button
        SystemTimeEditFieldLabel     matlab.ui.control.Label
        SystemTime                   matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Serial = serial(seriallist,'BaudRate',115200);
        
        Nodes = 1:8;
        
        Neighbor_Table = inf*ones(8,8);
        Node_Data = zeros(8,4)
        Nodes_w_data = [];
        System_Time = tic;
        
        Known_Nodes = [8];  % The only know node at startup is the base station.
        Active_Nodes = [];
        theta = linspace(0,2*pi-2*pi/7,7);
        
        Sensor_Data = [];
    end

    methods (Access = private)
    
        function Append_Neighbors(app, Message)
            Message = strsplit(Message,',');
            Node = str2double(Message{2});
            
            % Append New Nodes and fill in Neighbor Table
            for i = 3:length(Message)
                Neighbor = strsplit(Message{i},'_');
                NeighborID = str2double(Neighbor{1});
                NeighborStrength = str2double(Neighbor{2});
                if NeighborID == 9 || NeighborID == 0
                    continue;
                end
                if ~ismember(NeighborID,app.Known_Nodes)
                    app.Known_Nodes = [app.Known_Nodes,NeighborID];
                end
                app.Neighbor_Table(Node,NeighborID) = NeighborStrength;
            end
            
            app.Active_Nodes = [app.Active_Nodes,Node];
            app.Update_TextBox(num2str(Node),3)
            app.Update_TextBox(num2str(app.Active_Nodes),3)
            app.Update_Routing_Graph();
        end
        
        function update_buttons(app)
            %-----------------Node1
            if ismember(1,app.Active_Nodes)
                set(app.Query_1,'enable','on');
            else 
                set(app.Query_1,'enable','off');
            end
            if ismember(1,app.Known_Nodes)
                set(app.Refresh_1,'enable','on');
            else 
                set(app.Refresh_1,'enable','off');
            end
            %-----------------Node2
            if ismember(2,app.Active_Nodes)
                set(app.Query_2,'enable','on');
            else 
                set(app.Query_2,'enable','off');
            end
            if ismember(2,app.Known_Nodes)
                set(app.Refresh_2,'enable','on');
            else 
                set(app.Refresh_2,'enable','off');
            end
            %-----------------Node3
            if ismember(3,app.Active_Nodes)
                set(app.Query_3,'enable','on');
            else 
                set(app.Query_3,'enable','off');
            end
            if ismember(3,app.Known_Nodes)
                set(app.Refresh_3,'enable','on');
            else 
                set(app.Refresh_3,'enable','off');
            end
            %-----------------Node4
            if ismember(4,app.Active_Nodes)
                set(app.Query_4,'enable','on');
            else 
                set(app.Query_4,'enable','off');
            end
            if ismember(4,app.Known_Nodes)
                set(app.Refresh_4,'enable','on');
            else 
                set(app.Refresh_4,'enable','off');
            end
            %-----------------Node5
            if ismember(5,app.Active_Nodes)
                set(app.Query_5,'enable','on');
            else 
                set(app.Query_5,'enable','off');
            end
            if ismember(5,app.Known_Nodes)
                set(app.Refresh_5,'enable','on');
            else 
                set(app.Refresh_5,'enable','off');
            end
            %-----------------Node6
            if ismember(6,app.Active_Nodes)
                set(app.Query_6,'enable','on');
            else 
                set(app.Query_6,'enable','off');
            end
            if ismember(6,app.Known_Nodes)
                set(app.Refresh_6,'enable','on');
            else 
                set(app.Refresh_6,'enable','off');
            end
            %-----------------Node7
            if ismember(7,app.Active_Nodes)
                set(app.Query_7,'enable','on');
            else 
                set(app.Query_7,'enable','off');
            end
            if ismember(7,app.Known_Nodes)
                set(app.Refresh_7,'enable','on');
            else 
                set(app.Refresh_7,'enable','off');
            end
            %-----------------Overall
            if length(app.Active_Nodes)>1
                set(app.GetDataButton,'enable','on');
            else
                set(app.GetDataButton,'enable','off');
            end
        end
        function disable_buttons(app)
            set(app.Refresh_1,'enable','off');
            set(app.Refresh_2,'enable','off');
            set(app.Refresh_3,'enable','off');
            set(app.Refresh_4,'enable','off');
            set(app.Refresh_5,'enable','off');
            set(app.Refresh_6,'enable','off');
            set(app.Refresh_7,'enable','off');

            set(app.Query_1,'enable','off');
            set(app.Query_2,'enable','off');
            set(app.Query_3,'enable','off');
            set(app.Query_4,'enable','off');
            set(app.Query_5,'enable','off');
            set(app.Query_6,'enable','off');
            set(app.Query_7,'enable','off');
            set(app.GetDataButton,'enable','off');
        end

        function path = FindRoute(app,Dest)
            
            Node_Positions = [.5+.4*cos(app.theta'),.5+.4*sin(app.theta');.5,.5];
            if ~ismember(Dest,app.Active_Nodes)
                path = Dijkstra4([app.Active_Nodes,Dest],app.Neighbor_Table.^2,Dest);
            else
                path = Dijkstra4(app.Active_Nodes,app.Neighbor_Table,Dest);
            end
            
            app.Update_Routing_Graph();
            hold(app.RoutingGraph,'on');
            for i = 1:size(path,1)-1
                line(app.RoutingGraph, [Node_Positions(path(i),1),Node_Positions(path(i+1),1)],[Node_Positions(path(i),2),Node_Positions(path(i+1),2)],'LineWidth',2);
            end  
            hold(app.RoutingGraph,'off');
            path = [9;path;zeros(8 - length(path),1)];
            drawnow;
        end
        
        function Update_Routing_Graph(app)
            Node_Positions = [.5+.4*cos(app.theta'),.5+.4*sin(app.theta');.5,.5];
            
            scatter(app.RoutingGraph, Node_Positions(app.Active_Nodes,1),Node_Positions(app.Active_Nodes,2),'gh','LineWidth',3);
            hold(app.RoutingGraph,'on');
            New = setxor(app.Known_Nodes, app.Active_Nodes);
            
            scatter(app.RoutingGraph, Node_Positions(New,1),Node_Positions(New,2),'co','LineWidth',3);
            
            Undescovered = setxor(app.Nodes,app.Known_Nodes);
            scatter(app.RoutingGraph, Node_Positions(Undescovered,1),Node_Positions(Undescovered,2),'rx','LineWidth',2);
            hold(app.RoutingGraph,'off');
            drawnow;
        end
        
        function Send_Message(app,Target,Type)
            timeout = 10;
            
            set(app.SystemTime,'Value', toc(app.System_Time));
            path = FindRoute(app, Target);
            PATH = strcat(num2str(path.'));
            PATH= PATH(find(~isspace(PATH)));
                
            Message = [num2str(Type),',',num2str(Target),',',PATH];
            flushinput(app.Serial)
            fprintf(app.Serial,Message);
            app.Update_TextBox(Message,2);
            tic;
            while true
                if toc > timeout
                    app.Update_TextBox('Message_Timeout',0);
                    break;
                end
                SMessage = fscanf(app.Serial);
                
                if ~isempty(SMessage)
                    app.Update_TextBox(SMessage,1);
                    if Type == 1 && str2double(SMessage(1)) == 2
                        app.Append_Neighbors(SMessage);
                        app.update_buttons();
                        break;
                    elseif Type == 3 && str2double(SMessage(1)) == 4
                        app.Update_DataTable(SMessage)
                        break;             
                    elseif str2double(SMessage(1)) == 7
                        app.Update_TextBox(SMessage,4);
                        app.Active_Nodes = app.Active_Nodes(find(app.Active_Nodes~=Target));
                        app.update_buttons();
                        break;
                    else
                        app.Update_TextBox(['SendMessage ' SMessage],3);
                    end
                else
                    app.Update_TextBox('Empty Serial Read',4);
                end
            end
        end
        
        function Update_DataTable(app, Message)
            Message = strsplit(Message,',');
            Node = str2double(Message{2});
           
            if ~ismember(Node,app.Nodes_w_data)
                app.Nodes_w_data = [app.Nodes_w_data,Node];
                app.Node_Data(Node,:) = [Node, 1,toc(app.System_Time),str2double(Message{3})];
            else
                app.Node_Data(Node,[1,2,4]) = [Node, 1,str2double(Message{3})];
            end
            set(app.DataTable,'Data',app.Node_Data(app.Nodes_w_data,:));
            %app.DataTable.Data = app.Node_Data(app.Nodes_w_data,:);
            drawnow;
        end
        
        function Update_TextBox(app, message, Dir)
            % Dir == 0 -> internal message
            % Dir == 1 -> incoming message
            % Dir == 2 -> outgoing message
            % Dir == 3 -> Debug message
            % Dir == 3 -> Error message
            
            currText = get(app.MessageTraffic,'Value');
            if Dir == 1
                currText{end+1}= ['Incoming: ',message];
            elseif Dir == 2
                currText{end+1}= ['Outgoing: ',message];
            elseif Dir == 3
                currText{end+1}= ['Debug: ',message];
            elseif Dir == 4
                currText{end+1}= ['Error: ',message];
            else
                currText{end+1}= message;
            end
            set(app.MessageTraffic,'Value',currText);
            drawnow;
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fclose(instrfind);
            tic;
            app.Update_Routing_Graph();
            set(app.SystemTime,'Value', toc(app.System_Time));
            pause(.1);
            fopen(app.Serial);
            app.disable_buttons();
        end

        % Button pushed function: InitializeButton
        function InitializeButtonPushed(app, event)
            app.System_Time = tic;
            set(app.SystemTime,'Value', toc(app.System_Time));
            app.Update_TextBox('Initialize Button Pressed',0);
            timeout = 20;
            
            
            app.Nodes_w_data = [];
            app.Known_Nodes = [8];  % The only know node at startup is the base station.
            app.Active_Nodes = [];
            app.Neighbor_Table = inf*ones(length(app.Nodes));
            
            tic;
            fprintf(app.Serial,'1,8,980000000');
            app.Update_TextBox('1,8,980000000',2);
            
            SMessage = fscanf(app.Serial);
            app.Update_TextBox(SMessage,1);
            app.Append_Neighbors(SMessage);
            Remaining_Nodes = setxor(app.Known_Nodes, app.Active_Nodes);
            while ~isempty(Remaining_Nodes) 
                Node = Remaining_Nodes(1);
                
                if toc>timeout
                    app.Update_TextBox(['Init Failed at Node ' Node],4);
                    break;
                end
                app.Send_Message(Node,1)
               
                Remaining_Nodes = setxor(app.Known_Nodes, app.Active_Nodes);
                app.LinkTable.Data = app.Neighbor_Table;
                
            end
            app.update_buttons();
                        
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(instrfind);
            delete(app)
        end

        % Button pushed function: Refresh_1
        function Refresh_1ButtonPushed(app, event)
            app.Update_TextBox('Refresh 1 Button Pressed',0);
            app.Send_Message(1,1);
        end

        % Button pushed function: Query_1
        function Query_1ButtonPushed(app, event)
            app.Update_TextBox('Query 1 Button Pressed',0);
            app.Send_Message(1,3);
        end

        % Button pushed function: Refresh_2
        function Refresh_2ButtonPushed(app, event)
            app.Update_TextBox('Refresh 2 Button Pressed',0);
            app.Send_Message(2,1);
        end

        % Button pushed function: Query_2
        function Query_2ButtonPushed(app, event)
            app.Update_TextBox('Query 2 Button Pressed',0);
            app.Send_Message(2,3);
        end

        % Button pushed function: Refresh_3
        function Refresh_3ButtonPushed(app, event)
            app.Update_TextBox('Refresh 3 Button Pressed',0);
            app.Send_Message(3,1);
        end

        % Button pushed function: Query_3
        function Query_3ButtonPushed(app, event)
            app.Update_TextBox('Query 3 Button Pressed',0);
            app.Send_Message(3,3);
        end

        % Button pushed function: Refresh_4
        function Refresh_4ButtonPushed(app, event)
            app.Update_TextBox('Refresh 4 Button Pressed',0);
            app.Send_Message(4,1);
        end

        % Button pushed function: Query_4
        function Query_4ButtonPushed(app, event)
            app.Update_TextBox('Query 4 Button Pressed',0);
            app.Send_Message(4,3);
        end

        % Button pushed function: Refresh_5
        function Refresh_5ButtonPushed(app, event)
            app.Update_TextBox('Refresh 5 Button Pressed',0);
            app.Send_Message(5,1);
        end

        % Button pushed function: Query_5
        function Query_5ButtonPushed(app, event)
            app.Update_TextBox('Query 5 Button Pressed',0);
            app.Send_Message(5,3);
        end

        % Button pushed function: Refresh_6
        function Refresh_6ButtonPushed(app, event)
            app.Update_TextBox('Refresh 6 Button Pressed',0);
            app.Send_Message(6,1);
        end

        % Button pushed function: Query_6
        function Query_6ButtonPushed(app, event)
            app.Update_TextBox('Query 6 Button Pressed',0);
            app.Send_Message(6,3);
        end

        % Button pushed function: Refresh_7
        function Refresh_7ButtonPushed(app, event)
            app.Update_TextBox('Refresh 7 Button Pressed',0);
            app.Send_Message(7,1);
        end

        % Button pushed function: Query_7
        function Query_7ButtonPushed(app, event)
            app.Update_TextBox('Query 7 Button Pressed',0);
            app.Send_Message(7,3);
        end

        % Callback function
        function Refresh_8ButtonPushed(app, event)
            
        end

        % Callback function
        function Query_8Pushed(app, event)
            
        end

        % Button pushed function: BaseStationRefreshButton
        function BaseStationRefreshButtonPushed(app, event)
            app.Update_TextBox('Base Station Refresh Button Pressed',0);
            app.Send_Message(8,1);
        end

        % Button pushed function: GetDataButton
        function GetDataButtonPushed(app, event)
            app.Send_Message(1,3);
            app.Send_Message(2,3);
            app.Send_Message(3,3);
            app.Send_Message(4,3);
            app.Send_Message(5,3);
            app.Send_Message(6,3);
            app.Send_Message(7,3);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.InitializeButton = uibutton(app.UIFigure, 'push');
            app.InitializeButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeButtonPushed, true);
            app.InitializeButton.Text = 'Initialize';
            app.DataTable = uitable(app.UIFigure);
            app.DataTable.ColumnName = {'Node'; 'State'; 'Startup Time'; 'Temp'; 'Mic 1'; 'Mic 2'; 'Mic 3'};
            app.DataTable.RowName = {};
            app.LinkTable = uitable(app.UIFigure);
            app.LinkTable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; 'Base Station'};
            app.LinkTable.RowName = {};
            app.RoutingGraph = uiaxes(app.UIFigure);
            title(app.RoutingGraph, 'Current Route')
            app.RoutingGraph.XLim = [0 1];
            app.RoutingGraph.YLim = [0 1];
            app.RoutingGraph.ZLim = [0 1];
            app.RoutingGraph.CLim = [0 1];
            app.RoutingGraph.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.RoutingGraph.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.RoutingGraph.ZTick = [0 0.5 1];
            app.MessageTrafficTextAreaLabel = uilabel(app.UIFigure);
            app.MessageTrafficTextAreaLabel.Text = 'Message Traffic';
            app.MessageTraffic = uitextarea(app.UIFigure);
            app.MessageTraffic.Editable = 'off';
            app.GetDataButton = uibutton(app.UIFigure, 'push');
            app.GetDataButton.ButtonPushedFcn = createCallbackFcn(app, @GetDataButtonPushed, true);
            app.GetDataButton.Text = 'Get Data';
            app.Refresh_1 = uibutton(app.UIFigure, 'push');
            app.Refresh_1.ButtonPushedFcn = createCallbackFcn(app, @Refresh_1ButtonPushed, true);
            app.Refresh_1.Text = 'Refresh';
            app.Query_1 = uibutton(app.UIFigure, 'push');
            app.Query_1.ButtonPushedFcn = createCallbackFcn(app, @Query_1ButtonPushed, true);
            app.Query_1.Text = 'Query';
            app.Node1Label = uilabel(app.UIFigure);
            app.Node1Label.Text = 'Node 1';
            app.Refresh_2 = uibutton(app.UIFigure, 'push');
            app.Refresh_2.ButtonPushedFcn = createCallbackFcn(app, @Refresh_2ButtonPushed, true);
            app.Refresh_2.Text = 'Refresh';
            app.Query_2 = uibutton(app.UIFigure, 'push');
            app.Query_2.ButtonPushedFcn = createCallbackFcn(app, @Query_2ButtonPushed, true);
            app.Query_2.Text = 'Query';
            app.Node2Label = uilabel(app.UIFigure);
            app.Node2Label.Text = 'Node 2';
            app.Refresh_3 = uibutton(app.UIFigure, 'push');
            app.Refresh_3.ButtonPushedFcn = createCallbackFcn(app, @Refresh_3ButtonPushed, true);
            app.Refresh_3.Text = 'Refresh';
            app.Query_3 = uibutton(app.UIFigure, 'push');
            app.Query_3.ButtonPushedFcn = createCallbackFcn(app, @Query_3ButtonPushed, true);
            app.Query_3.Text = 'Query';
            app.Node3Label = uilabel(app.UIFigure);
            app.Node3Label.Text = 'Node 3';
            app.Refresh_4 = uibutton(app.UIFigure, 'push');
            app.Refresh_4.ButtonPushedFcn = createCallbackFcn(app, @Refresh_4ButtonPushed, true);
            app.Refresh_4.Text = 'Refresh';
            app.Query_4 = uibutton(app.UIFigure, 'push');
            app.Query_4.ButtonPushedFcn = createCallbackFcn(app, @Query_4ButtonPushed, true);
            app.Query_4.Text = 'Query';
            app.Node4Label = uilabel(app.UIFigure);
            app.Node4Label.Text = 'Node 4';
            app.Refresh_5 = uibutton(app.UIFigure, 'push');
            app.Refresh_5.ButtonPushedFcn = createCallbackFcn(app, @Refresh_5ButtonPushed, true);
            app.Refresh_5.Text = 'Refresh';
            app.Query_5 = uibutton(app.UIFigure, 'push');
            app.Query_5.ButtonPushedFcn = createCallbackFcn(app, @Query_5ButtonPushed, true);
            app.Query_5.Text = 'Query';
            app.Node5Label = uilabel(app.UIFigure);
            app.Node5Label.Text = 'Node 5';
            app.Refresh_6 = uibutton(app.UIFigure, 'push');
            app.Refresh_6.ButtonPushedFcn = createCallbackFcn(app, @Refresh_6ButtonPushed, true);
            app.Refresh_6.Text = 'Refresh';
            app.Query_6 = uibutton(app.UIFigure, 'push');
            app.Query_6.ButtonPushedFcn = createCallbackFcn(app, @Query_6ButtonPushed, true);
            app.Query_6.Text = 'Query';
            app.Node6Label = uilabel(app.UIFigure);
            app.Node6Label.Text = 'Node 6';
            app.Refresh_7 = uibutton(app.UIFigure, 'push');
            app.Refresh_7.ButtonPushedFcn = createCallbackFcn(app, @Refresh_7ButtonPushed, true);
            app.Refresh_7.Text = 'Refresh';
            app.Query_7 = uibutton(app.UIFigure, 'push');
            app.Query_7.ButtonPushedFcn = createCallbackFcn(app, @Query_7ButtonPushed, true);
            app.Query_7.Text = 'Query';
            app.Node7Label = uilabel(app.UIFigure);
            app.Node7Label.Text = 'Node 7';
            app.SensorInterfaceLabel = uilabel(app.UIFigure);
            app.SensorInterfaceLabel.Text = 'Sensor Interface';
            app.BaseStationRefreshButton = uibutton(app.UIFigure, 'push');
            app.BaseStationRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @BaseStationRefreshButtonPushed, true);
            app.BaseStationRefreshButton.Text = 'Base Station Refresh';
            app.SystemTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SystemTimeEditFieldLabel.Text = 'System Time';
            app.SystemTime = uieditfield(app.UIFigure, 'numeric');
            app.SystemTime.ValueDisplayFormat = '%.0f';
            app.SystemTime.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = NodeInterface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end